classdef GraphsInputWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        VariablesLabel                matlab.ui.control.Label
        cLabel                        matlab.ui.control.Label
        bLabel                        matlab.ui.control.Label
        aLabel                        matlab.ui.control.Label
        m2Label                       matlab.ui.control.Label
        m1Label                       matlab.ui.control.Label
        qLabel                        matlab.ui.control.Label
        a0Label                       matlab.ui.control.Label
        FunctionLabel                 matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        ClearvaluesButton             matlab.ui.control.Button
        VariableValueLabel            matlab.ui.control.Label
        VariablesTextArea             matlab.ui.control.TextArea
        GeometricsequenceinputsLabel  matlab.ui.control.Label
        m2EditField                   matlab.ui.control.NumericEditField
        m1EditField                   matlab.ui.control.NumericEditField
        CalculateGeometricsequenceButton  matlab.ui.control.Button
        qEditField                    matlab.ui.control.NumericEditField
        a0EditField                   matlab.ui.control.NumericEditField
        PlotButton                    matlab.ui.control.Button
        cEditField                    matlab.ui.control.NumericEditField
        bEditField                    matlab.ui.control.NumericEditField
        aEditField                    matlab.ui.control.NumericEditField
        Image                         matlab.ui.control.Image
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = public)
        GraphsWindow
        callerApp
        a0
    end
    
    methods (Access = public)
        function FuncLblUpdate(app, Label)
            lbl = Label;
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            c = app.cEditField.Value;
            syms x;
            expression = a*x^2 + b*x + c;
            latexStr = "f(x) = " + latex(expression);
            lbl.Interpreter = "latex";
            lbl.Text = sprintf('$$%s$$', latexStr); 
        end
        
        function applyTheme(app)
            if app.callerApp.Theme == true
                if ~isempty(app.GraphsWindow)
                    app.GraphsWindow.applyTheme(true);
                end
                app.Image.ImageSource = "./Pictures/background2_night.png";
                app.a0Label.FontColor = [1.00, 1.00, 1.00];
                app.qLabel.FontColor = [1.00, 1.00, 1.00];
                app.m1Label.FontColor = [1.00, 1.00, 1.00];
                app.m2Label.FontColor = [1.00, 1.00, 1.00];
                app.aLabel.FontColor = [1.00, 1.00, 1.00];
                app.bLabel.FontColor = [1.00, 1.00, 1.00];
                app.cLabel.FontColor = [1.00, 1.00, 1.00];
                app.VariableValueLabel.FontColor = [1.00, 1.00, 1.00];
                app.VariablesLabel.FontColor = [1.00, 1.00, 1.00];
            elseif app.callerApp.Theme == false
                if ~isempty(app.GraphsWindow)
                    app.GraphsWindow.applyTheme(false);
                end
                app.Image.ImageSource = "./Pictures/background2.png";
                app.a0Label.FontColor = [0.00, 0.00, 0.00];
                app.qLabel.FontColor = [0.00, 0.00, 0.00];
                app.m1Label.FontColor = [0.00, 0.00, 0.00];
                app.m2Label.FontColor = [0.00, 0.00, 0.00];
                app.aLabel.FontColor = [0.00, 0.00, 0.00];
                app.bLabel.FontColor = [0.00, 0.00, 0.00];
                app.cLabel.FontColor = [0.00, 0.00, 0.00];
                app.VariableValueLabel.FontColor = [0.00, 0.00, 0.00];
                app.VariablesLabel.FontColor = [0.00, 0.00, 0.00];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp)
            FuncLblUpdate(app, app.Label);
            app.callerApp = callerApp;
            applyTheme(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            c = app.cEditField.Value;
            app.a0 = app.a0EditField.Value;
            q = app.qEditField.Value;

            if ( isempty(a)  || ...
                isempty(b)  || ...
                isempty(c)  || ...
                isempty(app.a0) || ...
                isempty(q) )
                f = msgbox("Too few inputs.","Error","error");
                pause(2);
                delete(f);
                return;
            end
        
            if ~isempty(app.GraphsWindow) && ...
                isvalid(app.GraphsWindow) && ...
                ishandle(app.GraphsWindow.UIFigure)
                delete(app.GraphsWindow.UIFigure);
            end
        
            app.GraphsWindow = GraphsWindow(app);
            app.GraphsWindow.applyTheme(app.callerApp.Theme);
            
            syms y(x);
            y(x) = a*x^2 + b*x + c;
 
            nRange = 1:10;
            sRange = app.a0 * q.^(nRange - 1); 
           
            QuaFunctAx = app.GraphsWindow.getAxes();
            GSeqAx = app.GraphsWindow.getAxes1();
            fplot(QuaFunctAx, y, 'LineWidth', 2);
            plot(GSeqAx, nRange, sRange, 'ro-', 'LineWidth', 2);
            
            xlabel(QuaFunctAx, 'x');
            ylabel(QuaFunctAx, 'f(x)');
            latexStr = latex(y);
            legend(QuaFunctAx, ['$' latexStr '$'], 'Interpreter', 'latex', 'Location', 'Best'); 
            grid(QuaFunctAx, 'on');

            xlabel(GSeqAx, 'n');
            ylabel(GSeqAx, 'an');
            legend(GSeqAx, "a0 = " + num2str(app.a0) + " ; q = " + num2str(q), 'Location', 'Best');
            grid(GSeqAx, 'on');
        end

        % Button pushed function: CalculateGeometricsequenceButton
        function CalculateGeometricsequenceButtonPushed(app, event)
            a0 = app.a0EditField.Value;
            q = app.qEditField.Value;
            m1 = app.m1EditField.Value;
            m2 = app.m2EditField.Value;
        
            if isempty(a0) || isempty(q) || isempty(m1) || isempty(m2)
                f = msgbox("Too few inputs.","Error","error");
                pause(2);
                delete(f);
                return;
            elseif m1 > m2 || m1 == m2
                f = msgbox("m1 must be less than m2.","Error","error");
                pause(2);
                delete(f);
                return;
            elseif mod(abs(m1), 1) ~= 0 && mod(abs(m2), 1) ~= 0
                f = msgbox("m1 and m2 must be Integers.","Error","error");
                pause(2);
                delete(f);
                return;
            elseif mod(abs(m1), 1) ~= 0
                f = msgbox("m1 must be an Integer.","Error","error");
                pause(2);
                delete(f);
                return;
            elseif mod(abs(m2), 1) ~= 0
                f = msgbox("m2 must be an Integer.","Error","error");
                pause(2);
                delete(f);
                return;
            end
        
            if ~exist('DataOutput', 'dir')
                mkdir('DataOutput');
            end
        
            fp = fopen("DataOutput\VystupPostupnisti.txt", "w");
        
            fprintf(fp, "Súčet prvých N členov:\n");
            Ns = [10, 50, 100, 1000, 10000, 100000, 1000000];
            for i = 1:length(Ns)
                n = Ns(i);
        
                a0_sym = vpa(a0);
                q_sym = vpa(q);
                sumN = a0_sym * (q_sym^n - 1) / (q_sym - 1);
        
                if isinf(double(sumN))
                    fprintf(fp, "N = %d, Sum = %.2f * ((%.2f)^%d - 1) / (%.2f - 1)\n", n, a0, q, n, q);
                else
                    fprintf(fp, "N = %d, Sum = %.15f\n", n, double(sumN));
                end
            end
        
            m1_sym = vpa(m1);
            m2_sym = vpa(m2);
            s_m1tom2 = a0_sym * q_sym^(m1_sym - 1) * (q_sym^(m2_sym - m1_sym + 1) - 1) / (q_sym - 1);
            fprintf(fp, "\nSúčet od m_%d po m_%d = %.15f\n", m1, m2, double(s_m1tom2));
        
            if abs(q_sym) < 1
                S_infinite = a0_sym / (1 - q_sym);
                fprintf(fp, "\nNekonečný súčet = %.15f\n", double(S_infinite));
            else
                fprintf(fp, "\nNekonečný súčet neexistuje (|q| >= 1).\n");
            end
        
            fclose(fp);
        end

        % Value changed function: a0EditField, aEditField, bEditField, 
        % ...and 4 other components
        function UpdateWorkspace(app, event)
            FuncLblUpdate(app, app.Label);
            variables = {
                'a', app.aEditField.Value;
                'b', app.bEditField.Value;
                'c', app.cEditField.Value;
                'a0', app.a0EditField.Value;
                'q', app.qEditField.Value;
                'm1', app.m1EditField.Value;
                'm2', app.m2EditField.Value
            };

            WorkSpace = {};
            
            for i = 1:size(variables, 1)
                varName = variables{i, 1};
                varValue = variables{i, 2};
                if ~isempty(varValue)
                    WorkSpace{end+1} = sprintf('%s\t\t\t%.2f', varName, varValue);
                end
            end
         
            app.VariablesTextArea.Value = WorkSpace;
        end

        % Button pushed function: ClearvaluesButton
        function ClearValues(app, event)
            app.aEditField.Value = 0;
            app.bEditField.Value = 0;
            app.cEditField.Value = 0;
            app.a0EditField.Value = [];
            app.qEditField.Value = [];
            app.m1EditField.Value = [];
            app.m2EditField.Value = [];
            app.VariablesTextArea.Value = '';
            FuncLblUpdate(app, app.Label);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Pictures', 'background2.png');
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateWorkspace, true);
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateWorkspace, true);
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateWorkspace, true);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.a0EditField = uieditfield(app.UIFigure, 'numeric');
            app.a0EditField.AllowEmpty = 'on';
            app.a0EditField.ValueChangedFcn = createCallbackFcn(app, @UpdateWorkspace, true);
            app.a0EditField.Value = [];
            app.qEditField = uieditfield(app.UIFigure, 'numeric');
            app.qEditField.AllowEmpty = 'on';
            app.qEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateWorkspace, true);
            app.qEditField.Value = [];
            app.CalculateGeometricsequenceButton = uibutton(app.UIFigure, 'push');
            app.CalculateGeometricsequenceButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateGeometricsequenceButtonPushed, true);
            app.CalculateGeometricsequenceButton.Text = 'Calculate (Geometric sequence)';
            app.m1EditField = uieditfield(app.UIFigure, 'numeric');
            app.m1EditField.AllowEmpty = 'on';
            app.m1EditField.ValueChangedFcn = createCallbackFcn(app, @UpdateWorkspace, true);
            app.m1EditField.Value = [];
            app.m2EditField = uieditfield(app.UIFigure, 'numeric');
            app.m2EditField.AllowEmpty = 'on';
            app.m2EditField.ValueChangedFcn = createCallbackFcn(app, @UpdateWorkspace, true);
            app.m2EditField.Value = [];
            app.GeometricsequenceinputsLabel = uilabel(app.UIFigure);
            app.GeometricsequenceinputsLabel.Text = 'Geometric sequence inputs';
            app.VariablesTextArea = uitextarea(app.UIFigure);
            app.VariablesTextArea.Editable = 'off';
            app.VariableValueLabel = uilabel(app.UIFigure);
            app.VariableValueLabel.Text = 'Variable           Value';
            app.ClearvaluesButton = uibutton(app.UIFigure, 'push');
            app.ClearvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearValues, true);
            app.ClearvaluesButton.Text = 'Clear values';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.FunctionLabel = uilabel(app.UIFigure);
            app.FunctionLabel.Text = 'Function:';
            app.a0Label = uilabel(app.UIFigure);
            app.a0Label.Text = 'a0';
            app.qLabel = uilabel(app.UIFigure);
            app.qLabel.Text = 'q';
            app.m1Label = uilabel(app.UIFigure);
            app.m1Label.Text = 'm1';
            app.m2Label = uilabel(app.UIFigure);
            app.m2Label.Text = 'm2';
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a';
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b';
            app.cLabel = uilabel(app.UIFigure);
            app.cLabel.Text = 'c';
            app.VariablesLabel = uilabel(app.UIFigure);
            app.VariablesLabel.Text = 'Variables';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraphsInputWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end