classdef GraphsWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        qLabel       matlab.ui.control.Label
        qSlider      matlab.ui.control.Slider
        Label        matlab.ui.control.Label
        GraphsLabel  matlab.ui.control.Label
        UIAxes       matlab.ui.control.UIAxes
        UIAxes2      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callerApp
    end
    
    methods (Access = public)
        function ax = getAxes(app)
            ax = app.UIAxes;
        end

        function ax = getAxes1(app)
            ax = app.UIAxes2;
        end

        function applyTheme(app, Theme)
            if Theme == true
                app.UIFigure.Color = [0.16, 0.16, 0.16];
                app.UIAxes.XColor = [1 1 1];
                app.UIAxes.YColor = [1 1 1];
                app.UIAxes2.XColor = [1 1 1];
                app.UIAxes2.YColor = [1 1 1];
                app.GraphsLabel.FontColor = [1, 1, 1];
                app.Label.FontColor = [1, 1, 1];
                app.qLabel.FontColor = [1, 1, 1];
                app.qSlider.FontColor = [1, 1, 1];
            elseif Theme == false
                app.UIFigure.Color = [1.00, 1.00, 1.00];
                app.UIAxes.XColor = [0.16 0.16 0.16];
                app.UIAxes.YColor = [0.16 0.16 0.16];
                app.UIAxes2.XColor = [0.16 0.16 0.16];
                app.UIAxes2.YColor = [0.16 0.16 0.16];
                app.GraphsLabel.FontColor = [0, 0, 0];
                app.Label.FontColor = [0, 0, 0];
                app.qLabel.FontColor = [0, 0, 0];
                app.qSlider.FontColor = [0, 0, 0];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp)
            app.callerApp = callerApp;
            app.callerApp.FuncLblUpdate(app.Label);
        end

        % Callback function
        function ButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Value changing function: qSlider
        function qSliderValueChanging(app, event)
            q = event.Value;
            nRange = 1:10;
            sRange = app.callerApp.a0 * q.^(nRange - 1);
            if q < 0
                plot(app.UIAxes2, nRange, sRange, 'ro', 'LineWidth', 2);
            elseif q >= 0
                plot(app.UIAxes2, nRange, sRange, 'ro-', 'LineWidth', 2);
            end
            legend(app.UIAxes2, "a0 = " + num2str(app.callerApp.a0) + " ; q = " + num2str(q), 'Location', 'Best');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GraphsLabel = uilabel(app.UIFigure);
            app.GraphsLabel.Text = 'Graphs';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.qSlider = uislider(app.UIFigure);
            app.qSlider.Limits = [-5 5];
            app.qSlider.ValueChangingFcn = createCallbackFcn(app, @qSliderValueChanging, true);
            app.qLabel = uilabel(app.UIFigure);
            app.qLabel.Text = 'q';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraphsWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end