classdef Statistika < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MesiacDropDown           matlab.ui.control.DropDown
        MesiacLabel              matlab.ui.control.Label
        VzdialenosvkmLabel       matlab.ui.control.Label
        NadmorskvkaLabel         matlab.ui.control.Label
        NajstButton              matlab.ui.control.Button
        VzdialenostvkmEditField  matlab.ui.control.NumericEditField
        VypocitatButton          matlab.ui.control.Button
        NadmorskavyskaEditField  matlab.ui.control.NumericEditField
        Image                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        nadmorska_vyska
        Mesiac
        Vzdialenost
        callerApp
    end
    
    methods (Access = public)
        function applyTheme(app, Theme)
            if Theme == true
                app.MesiacLabel.FontColor = [1, 1, 1];
                app.NadmorskvkaLabel.FontColor = [1, 1, 1];
                app.VzdialenosvkmLabel.FontColor = [1, 1, 1];
                app.Image.ImageSource = './Pictures/background3_night.png';
                app.UIFigure.Color = [0.16, 0.16, 0.16];
            elseif Theme == false
                app.MesiacLabel.FontColor = [0, 0, 0];
                app.NadmorskvkaLabel.FontColor = [0, 0, 0];
                app.VzdialenosvkmLabel.FontColor = [0, 0, 0];
                app.Image.ImageSource = './Pictures/background3.png';
                app.UIFigure.Color = [1, 1, 1];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp)
            app.callerApp = callerApp;
            if app.callerApp.Theme == true
                applyTheme(app, true);
            else
                applyTheme(app, false);
            end
        end

        % Button pushed function: VypocitatButton
        function VypocitatButtonPushed(app, event)
            if isempty(app.Mesiac)
                app.Mesiac = 'Januar';
            end
            if isempty(app.nadmorska_vyska)
                f = msgbox("Invalid input.", "Error", "error");
                pause(2);
                delete(f);
            elseif (app.nadmorska_vyska < 0)
                f = msgbox("Invalid value. Value must be greater or equal to 0", "Error", "error");
                pause(2);
                delete(f);
            else
                app.NajstButton.Enable = 'off';
                app.VypocitatButton.Enable = 'off';
                pause(2);
                test(app.Mesiac, app.nadmorska_vyska);
                pause(2);
                app.NajstButton.Enable = 'on';
                app.VypocitatButton.Enable = 'on';
            end
        end

        % Value changed function: MesiacDropDown
        function MesiacDropDownValueChanged(app, event)
            app.Mesiac = app.MesiacDropDown.Value;
        end

        % Value changed function: NadmorskavyskaEditField
        function NadmorskavyskaEditFieldValueChanged(app, event)
            app.nadmorska_vyska = app.NadmorskavyskaEditField.Value;            
        end

        % Button pushed function: NajstButton
        function NajstButtonPushed(app, event)
            if isempty(app.Vzdialenost)
                f = msgbox("Invalid input.", "Error", "error");
                pause(2);
                delete(f);
            elseif(app.Vzdialenost < 0)
                f = msgbox("Invalid value. Value must be greater or equal to 0", "Error", "error");
                pause(2);
                delete(f);
            else
                app.NajstButton.Enable = 'off';
                app.VypocitatButton.Enable = 'off';
                pause(2);
                calculate_vzdialenost(app.Vzdialenost);
                pause(2);
                app.NajstButton.Enable = 'on';
                app.VypocitatButton.Enable = 'on';
            end
        end

        % Value changed function: VzdialenostvkmEditField
        function VzdialenostvkmEditFieldValueChanged(app, event)
            app.Vzdialenost = app.VzdialenostvkmEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Pictures', 'background3.png');
            app.NadmorskavyskaEditField = uieditfield(app.UIFigure, 'numeric');
            app.NadmorskavyskaEditField.Limits = [-500 5000];
            app.NadmorskavyskaEditField.AllowEmpty = 'on';
            app.NadmorskavyskaEditField.ValueChangedFcn = createCallbackFcn(app, @NadmorskavyskaEditFieldValueChanged, true);
            app.NadmorskavyskaEditField.Value = [];
            app.VypocitatButton = uibutton(app.UIFigure, 'push');
            app.VypocitatButton.ButtonPushedFcn = createCallbackFcn(app, @VypocitatButtonPushed, true);
            app.VypocitatButton.Text = 'Vypocitat''';
            app.VzdialenostvkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.VzdialenostvkmEditField.Limits = [-1000 20000];
            app.VzdialenostvkmEditField.AllowEmpty = 'on';
            app.VzdialenostvkmEditField.ValueChangedFcn = createCallbackFcn(app, @VzdialenostvkmEditFieldValueChanged, true);
            app.VzdialenostvkmEditField.Value = [];
            app.NajstButton = uibutton(app.UIFigure, 'push');
            app.NajstButton.ButtonPushedFcn = createCallbackFcn(app, @NajstButtonPushed, true);
            app.NajstButton.Text = 'Najst''';
            app.NadmorskvkaLabel = uilabel(app.UIFigure);
            app.NadmorskvkaLabel.Text = 'Nadmorská výška';
            app.VzdialenosvkmLabel = uilabel(app.UIFigure);
            app.VzdialenosvkmLabel.Text = 'Vzdialenosť v km';
            app.MesiacLabel = uilabel(app.UIFigure);
            app.MesiacLabel.Text = 'Mesiac';
            app.MesiacDropDown = uidropdown(app.UIFigure);
            app.MesiacDropDown.Items = {'Januar', 'Februar', 'Marec', 'April', 'Maj', 'Jun', 'Jul', 'August', 'September', 'Oktober', 'November', 'December'};
            app.MesiacDropDown.ValueChangedFcn = createCallbackFcn(app, @MesiacDropDownValueChanged, true);
            app.MesiacDropDown.Value = 'Januar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Statistika(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end