classdef menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Image2              matlab.ui.control.Image
        Image               matlab.ui.control.Image
        Switch              matlab.ui.control.Switch
        MenuLabel           matlab.ui.control.Label
        GrafyButton         matlab.ui.control.Button
        MaticeButton        matlab.ui.control.Button
        tatistikaButton     matlab.ui.control.Button
        UkoniprogramButton  matlab.ui.control.Button
        Image4              matlab.ui.control.Image
    end

    properties (Access = public)
        Theme
        GraphsInputWindow
        GraphsWindow
        Statistika
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UkoniprogramButton
        function UkoniprogramButtonPushed(app, event)
            choice = uiconfirm(app.UIFigure, ...
                'Naozaj chcete ukončiť program?', ...
                'Potvrdenie ukončenia', ...
                'Options', {'Áno', 'Nie'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);

            if strcmp(choice, 'Áno')
                if ~isempty(app.GraphsInputWindow) && isa(app.GraphsInputWindow, 'handle') && isvalid(app.GraphsInputWindow)
                    if ~isempty(app.GraphsInputWindow.GraphsWindow) && ...
                        isa(app.GraphsInputWindow.GraphsWindow, 'handle') && ...
                        isvalid(app.GraphsInputWindow.GraphsWindow)
                        if ~isempty(app.GraphsInputWindow.GraphsWindow.UIFigure) && ...
                            isa(app.GraphsInputWindow.GraphsWindow.UIFigure, 'handle') && ...
                            isvalid(app.GraphsInputWindow.GraphsWindow.UIFigure)
                            delete(app.GraphsInputWindow.GraphsWindow.UIFigure);
                        end
                    end
                end
    
                if ~isempty(app.GraphsInputWindow) && ...
                    isa(app.GraphsInputWindow, 'handle') && ...
                    isvalid(app.GraphsInputWindow)
                    if ~isempty(app.GraphsInputWindow.UIFigure) && ...
                        isa(app.GraphsInputWindow.UIFigure, 'handle') && ...
                            isvalid(app.GraphsInputWindow.UIFigure)
                            delete(app.GraphsInputWindow.UIFigure);
                    end
                end

                if ~isempty(app.Statistika) && ...
                    isa(app.Statistika, 'handle') && ...
                    isvalid(app.Statistika)
                    if ~isempty(app.Statistika.UIFigure) && ...
                        isa(app.Statistika.UIFigure, 'handle') && ...
                            isvalid(app.Statistika.UIFigure)
                            delete(app.Statistika.UIFigure);
                    end
                end
    
                if ~isempty(app.UIFigure) && isa(app.UIFigure, 'handle') && isvalid(app.UIFigure)
                    delete(app.UIFigure);
                end
            end

        end

        % Button pushed function: tatistikaButton
        function StatistikaButtonPushed(app, event)
            name_of_file = 'DataInput/SVP-Statistika.xlsx';
            if exist(name_of_file, 'file') == 2
                Statistika_vystup();
                function_Statistika();
                if isempty(app.Statistika) || ~isvalid(app.Statistika)
                    app.Statistika = Statistika(app);
                end
            else
                uialert(app.UIFigure, ...
                    sprintf('Subor "%s" ne bol najdeny v "%s".', 'SVP-Statistika.xlsx', 'Data.input'), ...
                    'FileControl', ...
                    'Icon', 'error');
            end
        end

        % Button pushed function: MaticeButton
        function MaticeButtonPushed(app, event)
            GenerateMatrices();
            MaticeVysledky();
            f = msgbox("Výsledky boli zapisane do súboru „MaticeVysledky.txt“ v priečinku „DataOutput“.","Success","success");
            pause(2);
            delete(f);
        end

        % Button pushed function: GrafyButton
        function GrafyButtonPushed(app, event)
            if isempty(app.GraphsInputWindow) || ~isvalid(app.GraphsInputWindow)
                app.GraphsInputWindow = GraphsInputWindow(app);
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if strcmp(app.Switch.Value, '  ')
                app.Theme = true;
                if ~isempty(app.GraphsInputWindow) && isvalid(app.GraphsInputWindow)
                    app.GraphsInputWindow.applyTheme();
                else
                    app.GraphsInputWindow = [];
                end

                if ~isempty(app.Statistika) && isvalid(app.Statistika)
                    app.Statistika.applyTheme(true);
                else
                    app.Statistika = [];
                end
                app.UIFigure.Color = [0.21, 0.21, 0.21];
                app.Image.ImageSource = './Pictures/sun_yellow.png';
                app.Image2.ImageSource = './Pictures/moon_yellow.png';
                app.Image4.ImageSource = './Pictures/background_night.png';
                app.MenuLabel.FontColor = [0.00, 0.00, 0.00];
                app.GrafyButton.BackgroundColor = [0.16, 0.16, 0.16];
                app.tatistikaButton.BackgroundColor = [0.16, 0.16, 0.16];
                app.MaticeButton.BackgroundColor = [0.16, 0.16, 0.16];
                app.UkoniprogramButton.BackgroundColor = [0.16, 0.16, 0.16];
                app.GrafyButton.FontColor = [1.00, 1.00, 1.00];
                app.tatistikaButton.FontColor = [1.00, 1.00, 1.00];
                app.MaticeButton.FontColor = [1.00, 1.00, 1.00];
                app.UkoniprogramButton.FontColor = [1.00, 1.00, 1.00];
            elseif strcmp(app.Switch.Value, ' ')
                app.Theme = false;
                if ~isempty(app.GraphsInputWindow) && isvalid(app.GraphsInputWindow)
                    app.GraphsInputWindow.applyTheme();
                else
                    app.GraphsInputWindow = [];
                end
                if ~isempty(app.Statistika) && isvalid(app.Statistika)
                    app.Statistika.applyTheme(false);
                else
                    app.Statistika = [];
                end
                app.UIFigure.Color = [0.87, 0.89, 0.94];
                app.Image.ImageSource = './Pictures/sun.png';
                app.Image2.ImageSource = './Pictures/moon.png';
                app.Image4.ImageSource = './Pictures/background.png';
                app.MenuLabel.FontColor = [1.00, 1.00, 1.00];
                app.GrafyButton.BackgroundColor = [1.00, 1.00, 1.00];
                app.tatistikaButton.BackgroundColor = [1.00, 1.00, 1.00];
                app.MaticeButton.BackgroundColor = [1.00, 1.00, 1.00];
                app.UkoniprogramButton.BackgroundColor = [1.00, 1.00, 1.00];
                app.GrafyButton.FontColor = [0.00, 0.00, 0.00];
                app.tatistikaButton.FontColor = [0.00, 0.00, 0.00];
                app.MaticeButton.FontColor = [0.00, 0.00, 0.00];
                app.UkoniprogramButton.FontColor = [0.00, 0.00, 0.00];
            end             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'cool');
            app.UIFigure.Name = 'MATLAB App';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Pictures', 'background.png');
            app.UkoniprogramButton = uibutton(app.UIFigure, 'push');
            app.UkoniprogramButton.ButtonPushedFcn = createCallbackFcn(app, @UkoniprogramButtonPushed, true);
            app.UkoniprogramButton.Text = 'Ukonči program';
            app.tatistikaButton = uibutton(app.UIFigure, 'push');
            app.tatistikaButton.ButtonPushedFcn = createCallbackFcn(app, @StatistikaButtonPushed, true);
            app.tatistikaButton.Text = 'Štatistika';
            app.MaticeButton = uibutton(app.UIFigure, 'push');
            app.MaticeButton.ButtonPushedFcn = createCallbackFcn(app, @MaticeButtonPushed, true);
            app.MaticeButton.Text = 'Matice';
            app.GrafyButton = uibutton(app.UIFigure, 'push');
            app.GrafyButton.ButtonPushedFcn = createCallbackFcn(app, @GrafyButtonPushed, true);
            app.GrafyButton.Text = 'Grafy';
            app.MenuLabel = uilabel(app.UIFigure);
            app.MenuLabel.Text = '🕹️Menu';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {' ', '  '};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = ' ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Pictures', 'sun.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Pictures', 'moon.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end